classdef explore_factors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploreFactorsUIFigure        matlab.ui.Figure
        BranchDropDown                matlab.ui.control.DropDown
        BranchDropDownLabel           matlab.ui.control.Label
        ReloadOriginalDataPanel       matlab.ui.container.Panel
        RenderingMode                 matlab.ui.control.DropDown
        RenderingLabel                matlab.ui.control.Label
        LoadTrialButton               matlab.ui.control.Button
        LoaddataatTimepointButton     matlab.ui.control.Button
        SelectEventButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        RawTab                        matlab.ui.container.Tab
        ThisstepcanberedoneoncePeakdetecioniscompleteLabel_2  matlab.ui.control.Label
        SmoothingsSpinner             matlab.ui.control.Spinner
        SmoothingsSpinnerLabel        matlab.ui.control.Label
        ThisstepcanberedoneoncePeakdetecioniscompleteLabel  matlab.ui.control.Label
        CutoffSpinner                 matlab.ui.control.Spinner
        CutoffSpinnerLabel            matlab.ui.control.Label
        FindBadROIsButton             matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        PrepareBinningTab             matlab.ui.container.Tab
        DisplayButtonGroup            matlab.ui.container.ButtonGroup
        mediantracescaledButton       matlab.ui.control.RadioButton
        mediantracerawButton          matlab.ui.control.RadioButton
        BinMetricEditField            matlab.ui.control.NumericEditField
        BinMetricEditFieldLabel       matlab.ui.control.Label
        BinningModeDropDown           matlab.ui.control.DropDown
        BinningModeDropDownLabel      matlab.ui.control.Label
        PeakDetectionTab              matlab.ui.container.Tab
        UpdateBadROIsButton           matlab.ui.control.Button
        DetectButton                  matlab.ui.control.Button
        MethodDropDown_4              matlab.ui.control.DropDown
        MethodDropDown_4Label         matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        CustomRangeEditField          matlab.ui.control.EditField
        CustomRangeEditFieldLabel     matlab.ui.control.Label
        DetectUsingDropDown           matlab.ui.control.DropDown
        DetectUsingDropDownLabel      matlab.ui.control.Label
        DetectedEvents                matlab.ui.control.UIAxes
        RescaleTab                    matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        IndividuaScalingTab           matlab.ui.container.Tab
        IndividualRescaleFig          matlab.ui.control.UIAxes
        GlobalScalingTab              matlab.ui.container.Tab
        GlobalRescaleFig              matlab.ui.control.UIAxes
        ImageTab                      matlab.ui.container.Tab
        ScaledIm                      matlab.ui.control.UIAxes
        SuggestedvalueLabel           matlab.ui.control.Label
        SmoothinginptsEditField       matlab.ui.control.NumericEditField
        SmoothinginptsEditFieldLabel  matlab.ui.control.Label
        MethodDropDown_3              matlab.ui.control.DropDown
        MethodDropDown_3Label         matlab.ui.control.Label
        RescaleEventsButton           matlab.ui.control.Button
        DimReductionTab               matlab.ui.container.Tab
        MetricDropDown                matlab.ui.control.DropDown
        MetricDropDownLabel           matlab.ui.control.Label
        RefreshButton                 matlab.ui.control.Button
        TreeDisplayButtonGroup        matlab.ui.container.ButtonGroup
        NClustEditField               matlab.ui.control.NumericEditField
        NClustEditFieldLabel          matlab.ui.control.Label
        NFactorsSpinner               matlab.ui.control.Spinner
        NFactorsSpinnerLabel          matlab.ui.control.Label
        MethodDropDown_2              matlab.ui.control.DropDown
        MethodDropDown_2Label         matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        ClustersButton                matlab.ui.control.ToggleButton
        FactorsButton                 matlab.ui.control.ToggleButton
        BranchesButton                matlab.ui.control.ToggleButton
        Loadings_fig                  matlab.ui.control.UIAxes
        SpinedetectionTab             matlab.ui.container.Tab
        ClusternumberSpinner          matlab.ui.control.Spinner
        ClusternumberSpinnerLabel     matlab.ui.control.Label
        ThrpeaksEditField             matlab.ui.control.NumericEditField
        ThrpeaksEditFieldLabel        matlab.ui.control.Label
        magicButton                   matlab.ui.control.Button
        DetectSpinesButton            matlab.ui.control.Button
        ShowexcludedROIsCheckBox      matlab.ui.control.CheckBox
        GenerateimagesButton          matlab.ui.control.Button
        Tree                          matlab.ui.container.CheckBoxTree
        Traces_fig                    matlab.ui.control.UIAxes
        Tree_fig                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        expe % Description
        highlight_trace % Description
        current_record_data % Description
        current_record_number = 0 % Description
        current_bap = [];
        event_index = [];
    end
    
    methods (Access = private)
        
        function embed_tree(app, fig_num)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end
            target = app.Tree_fig;
            
            cla(target)
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax.Children, target); 
            rotate3d(target,'on');axis(target, 'off')
            adjust_tree_plot('3D view',target);
            target.YLim = ax.YLim; target.XLim = ax.XLim; target.YDir = ax.YDir;target.CLim = ax.CLim;target.Title = ax.Title;
            caxis(app.Tree_fig,[0,350]);
            close(fig);
        end
        
        function embed_image(app, fig_num, target)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end    
            if nargin < 3 
                target = app.Loadings_fig;
            end
            
            cla(target);
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax, target); 
            target.YLim = ax.YLim; target.XLim = ax.XLim; target.YDir = ax.YDir;target.CLim = ax.CLim;target.Title = ax.Title;
            close(fig);
        end
        
        function embed_trace(app, fig_num, target)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end
            if nargin < 3 
                target = app.Traces_fig;
            end
            
            cla(target)
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax.Children, target); 
            target.YLim = ax.YLim; target.XLim = ax.XLim; target.YDir = ax.YDir;target.Title = ax.Title;
            close(fig);
        end
        
        
        function im = load_bap(app, record, pt_in_record, window)            
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params('data_folder',ap.data_folder,...
                                'data_type'      , 'concatenated',...
                                   'rendering_mode',  app.RenderingMode.Value,...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , false         ,...
                                   'z_score'        ,-5,...
                                   'ROIs', find(app.expe.dimensionality.valid_trace_idx)); 
            
            if app.ShowexcludedROIsCheckBox.Value
                p.ROIs = 0;
            end
            
            if record == app.current_record_number
                data = app.current_record_data;
            else
                data = load_ribbon_scan(p);   
                app.current_record_number = record;
                app.current_record_data = data;
            end
            
            if nargin > 3 && ~isempty(window)
                window = window(window > 0 & window <= size(data{1}, 4));
                im = max_projection(data{1}(:,:,:,window,:),'','mean');                
            else
                lim = [5,10];
                im = max_projection(data{1}(:,:,:,max(pt_in_record-lim(1), 1):min(pt_in_record+lim(2), end),:),'','mean');
            end
            im = im(:,:,1);
        end
        
        function pause(app)
            for el = app.ExploreFactorsUIFigure.Children'
                try
                    el.Enable = 'off';
                end                
            end
            pause(0.05)
        end
        
        
        function resume(app)
            for el = app.ExploreFactorsUIFigure.Children'
                try
                    el.Enable = 'on';
                end                
            end
            pause(0.05)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, expe)
            %% Assign object
            app.expe = expe;
            
            %% Build ROI table
            app.UITable.Data = [app.expe.ref.indices.swc_list(:,[1,5]), zeros(size(app.expe.ref.indices.swc_list, 1),2)];
            app.UITable.Data(app.expe.ref.indices.somatic_ROIs,3) = 1;
            s = uistyle('BackgroundColor','g');% 'FontAngle','italic','
            addStyle(app.UITable,s,'cell',[app.expe.ref.indices.somatic_ROIs',3*ones(size(app.expe.ref.indices.somatic_ROIs'))]);
            
            %% Display median trace 
            app.expe.plot_original_traces();
            app.embed_trace(1055);
        end

        % Selection changed function: TreeDisplayButtonGroup
        function TreeDisplayButtonGroupSelectionChanged(app, event)
            app.pause()
            mode = app.TreeDisplayButtonGroup.SelectedObject;
            app.BranchDropDown.Value = 'All'; % reset selection
            if strcmpi(mode.Text, 'branches')
                app.BranchDropDownLabel.Text = 'branch #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:numel(app.expe.ref.indices.br_start_idx)));
                app.BranchDropDown.Items{1} = 'All';
            elseif strcmpi(mode.Text, 'factors') 
                app.BranchDropDownLabel.Text = 'factor #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:app.expe.dimensionality.n_factors));
                app.BranchDropDown.Items{1} = 'All'; 
                app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                app.embed_tree(10200);
            elseif strcmpi(mode.Text, 'clusters') 
                app.BranchDropDownLabel.Text = 'cluster #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:nanmax(app.expe.dimensionality.cluster_idx)));
                app.BranchDropDown.Items{1} = 'All'; 
                app.expe.dimensionality.clust_meth         = app.MethodDropDown_2.Value;
                app.expe.dimensionality.N_clust     = app.NClustEditField.Value;                
                app.expe.cluster_factors();                
                app.expe.plot_cluster_tree('', app.Loadings_fig, app.Traces_fig); 
                app.embed_tree(gcf);
            end
            app.resume()
        end

        % Value changed function: BranchDropDown
        function BranchDropDownValueChanged(app, event)
            app.pause()
            value = str2num(app.BranchDropDown.Value);
            if strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'factors') 
                if ~isempty(value)
                    app.expe.plot_dim_tree(value);
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.all_ROIs(app.expe.dimensionality.valid_trace_idx));
                    value          = app.expe.dimensionality.LoadingsPM(:,value)/sum(app.expe.dimensionality.LoadingsPM(:,value));       
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, nanmean(rescaled_traces'.* value, 1),'Color','r','LineWidth',2);
                else
                    app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            elseif strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'clusters') 
                if ~isempty(value)
                    cluster_idx = app.expe.dimensionality.cluster_idx;
                    cluster_idx(cluster_idx ~= value) = 0;
                    app.expe.ref.plot_value_tree(cluster_idx, find(app.expe.dimensionality.valid_trace_idx), app.expe.default_handle, 'Clusters','',10200, 'regular', 'viridis');      
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.valid_trace_idx);
                    value           = nanmean(rescaled_traces(:, cluster_idx>0),2);
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, value,'Color','r','LineWidth',2);
                else
                    app.expe.plot_cluster_tree('',app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            end
            app.resume();
        end

        % Button pushed function: LoaddataatTimepointButton
        function LoaddataatTimepointButtonPushed(app, event)
            app.pause()
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            t = ginput(1);
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            t = t(1);t_starts = app.expe.timescale.t_start_nogap;
            record = find(t_starts < t, 1, 'last');
            t_in_record = t - t_starts(record);
            pt_in_record = round(t_in_record / app.expe.timescale.sr(record));
            
            app.load_bap(record, pt_in_record);
            app.resume();
        end

        % Button pushed function: LoadTrialButton
        function LoadTrialButtonPushed(app, event)
            app.pause()
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            pt = ginput(1);
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            t = pt(1);t_starts = app.expe.timescale.t_start_nogap;
            record = find(t_starts < t, 1, 'last');
            t_in_record = t - t_starts(record);
            pt_in_record = round(t_in_record / app.expe.timescale.sr(record));
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params(   'data_folder',ap.data_folder,...
                                   'data_type'      , 'concatenated',...
                                   'rendering_mode', app.RenderingMode.Value,...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , true         ,...
                                   'ROIs',find(app.expe.dimensionality.valid_trace_idx),...
                                   'z_score'        ,-5);
            data = load_ribbon_scan(p); 
            app.resume()
        end

        % Value changed function: MethodDropDown, NFactorsSpinner
        function MethodDropDownValueChanged(app, event)
            app.pause()
            app.expe.rendering = false;
            app.expe.get_dimensionality(false,app.NFactorsSpinner.Value,app.MetricDropDown.Value,app.MethodDropDown.Value,app.MethodDropDown_2.Value);
            app.expe.rendering = true;
            cla(app.Traces_fig)
            app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
            app.embed_tree(10200);
            app.resume()
        end

        % Value changed function: MethodDropDown_2, NClustEditField
        function MethodDropDown_2ValueChanged(app, event)
            app.pause()
            app.expe.dimensionality.clust_meth         = app.MethodDropDown_2.Value;
            app.expe.dimensionality.N_clust     = app.NClustEditField.Value;                
            app.expe.cluster_factors();
            app.expe.plot_cluster_tree('', app.Loadings_fig, app.Traces_fig); 
            app.embed_tree(10200);
            app.resume()
        end

        % Button pushed function: GenerateimagesButton
        function GenerateimagesButtonPushed(app, event)
            app.pause()
            events = [];
            selection = app.Tree.CheckedNodes;
            for el = 1:numel(selection)
                txt = selection(el).Text;
                if ~contains(txt, 'Record')
                    pos = strfind(txt , 't = ');
                    par = selection(el).Parent;
                    par_txt = str2double(par.Text(7:end));
                    if ~isempty(pos)
                        events = [events; [par_txt, str2double(txt(6:pos-1)), str2double(txt(pos+4:end))]];                    
                    end
                end
            end
            
            pt_starts = cumsum([1,app.expe.timescale.tp]);
            im = [];
            for ev = 1:size(events, 1)            
                record = events(ev, 1);
                tp = events(ev, 3);
                start_in_global = pt_starts(record) + 1;
                event_nb = events(ev, 2);
                event_bloc_idx = app.event_index(event_nb,3)
                window = app.expe.event.t_win{event_bloc_idx};
                im = cat(3, im, app.load_bap(record, tp - start_in_global, window- start_in_global));
                start = nanmax(events(ev, 3)-100, 1);
                stop = nanmin(events(ev, 3)+100, numel(app.expe.timescale.global_timescale));
                xlim(app.Traces_fig, app.expe.timescale.global_timescale([start, stop]));
                pause(0.05)
            end
            
            im = spatial_smoothing_5D(im,1,'anisotropic');

            show_stack_GUI(im)
            
            assignin('base','im',im)
            app.resume();
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = event.Source.SelectedNodes;
            pos =  strfind(checkedNodes.Text , 't = ');
            if ~isempty(pos)
                tp  =  str2double(checkedNodes.Text(pos+4:end));
                start = nanmax(tp-100, 1);
                stop = nanmin(tp+100, numel(app.expe.timescale.global_timescale));
                xlim(app.Traces_fig, app.expe.timescale.global_timescale([start, stop]));
                app.current_bap = plot(app.Traces_fig, app.expe.timescale.global_timescale([tp,tp]),[nanmin(app.Traces_fig.YLim), nanmax(app.Traces_fig.YLim)],'r--');
            end

        end

        % Button pushed function: SelectEventButton
        function SelectEventButtonPushed(app, event)
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            t = ginput(1);
            [~,t] = nanmin(abs(app.expe.timescale.global_timescale - t(1)));
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            all         =  vertcat(app.expe.event.peak_time{:});
            [~, loc]    = nanmin(abs(all-t(1))');
            record      = app.event_index(loc,1);
            event       = app.event_index(loc,2);
            %event_block = app.event_index(18,3);            
            idx = find(contains({app.Tree.Children(record).Children.Text}, ['Event ',num2str(event),' t = ']));
            app.Tree.CheckedNodes =  [app.Tree.CheckedNodes,;app.Tree.Children(record).Children(idx)];
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.MethodDropDownValueChanged();
            app.MethodDropDown_2ValueChanged();
        end

        % Value changed function: MetricDropDown
        function MetricDropDownValueChanged(app, event)
            app.RefreshButtonPushed();       
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmpi(selectedTab.Title, 'Dim Reduction')
                embed_imageDisplayButtonGroup.SelectedObject = app.TreeDisplayButtonGroup.Buttons(2);
                app.TreeDisplayButtonGroupSelectionChanged('');
                
                peak_t = vertcat(app.expe.event.peak_time{:});
                current = 1;
                for record = 1:numel(app.expe.timescale.tp)
                    start = current;
                    stop = current + app.expe.timescale.tp(record) - 1;
                    incl = find(peak_t >= start & peak_t <= stop);
                    Top_Node = app.Tree;
                    Event_Node = uitreenode(Top_Node,'Text',['Record ',num2str(record)]);
                    if ~isempty(incl)                    
                        for ev = incl'
                            uitreenode(Event_Node,'Text',['Event ',num2str(ev), ' t = ', num2str(peak_t(ev))]);  
                            ev_ori = find(cellfun(@(x) any(ismember(x,peak_t(ev))), app.expe.event.peak_time));
                            app.event_index(end+1,:) = [record, ev, ev_ori];
                        end
                    else
                        Event_Node.HandleVisibility = 'off';
                    end   
                    expand(Top_Node)
                    current = current + app.expe.timescale.tp(record);
                end
            elseif strcmpi(selectedTab.Title, 'Prepare Binning')
                try
                    app.expe.plot_distance_tree();
                    app.embed_tree(1081)
                end
                try
                    app.expe.plot_median_traces; 
                    app.embed_trace(1001)
                end
                
                %app.BinningModeDropDown.Value = app.expe.bi 
            elseif strcmpi(selectedTab.Title, 'Rescale')                
                app.SuggestedvalueLabel.Text = ['Suggested value : ',num2str(2*nanmedian(vertcat(app.expe.event.peak_width{:})))];
                try
                    app.expe.plot_rescaled_traces(); 
                    app.embed_trace(1033,app.ScaledIm)
                    app.embed_trace(1034)
                end
            elseif strcmpi(selectedTab.Title, 'Peak Detection') 
                try
                    obj.plot_detected_events
                    app.embed_trace(1035, app.DetectedEvents);
                end                
            elseif strcmpi(selectedTab.Title, 'Raw') 
                try
                    app.expe.plot_original_traces();
                    app.embed_trace(1055);
                end                
            end
        end

        % Value changed function: BinMetricEditField, 
        % BinningModeDropDown
        function BinningModeDropDownValueChanged(app, event)
            condition = app.BinningModeDropDown.Value;
            app.BinMetricEditField.Enable = 'off';                     
            if strcmp(condition, 'distance')
                app.BinMetricEditField.Enable = 'on';
                app.BinMetricEditField.Limits = [0,Inf];
                app.BinMetricEditField.Value = 50;
            elseif strcmp(condition, 'order')
                app.BinMetricEditField.Limits = [-Inf,Inf];
                app.BinMetricEditField.Value = 0;   
                % pass   
            elseif strcmp(condition, 'branch')
                app.BinMetricEditField.Limits = [-Inf,Inf];
                app.BinMetricEditField.Value = 0;   
                % pass
            elseif strcmp(condition, 'depth')
                R = [nanmin(app.expe.ref.original_tree{1}.Z) , nanmax(app.expe.ref.original_tree{1}.Z)];
                app.BinMetricEditField.Enable = 'on';
                app.BinMetricEditField.Limits = [1,R(2)-R(1)];
                app.BinMetricEditField.Value = nanmin(100, R(2)-R(1));    
            elseif strcmp(condition, 'random')
                app.BinMetricEditField.Enable = 'on';
                app.BinMetricEditField.Limits = [1,numel(app.expe.indices.valid_swc_rois)];
                app.BinMetricEditField.Value = 5;
            elseif strcmp(condition, 'category')
                app.BinMetricEditField.Limits = [-Inf,Inf];
                app.BinMetricEditField.Value = 0;   
                % pass
            end
            value = app.BinMetricEditField.Value;
            if ~value
                value = [];
            end
            
            %% Call binning function
            app.expe.prepare_binning({condition, value});
            
            %% integrate figures in GUI
            app.embed_trace(1001)
        end

        % Callback function: CutoffSpinner, FindBadROIsButton
        function DetectbadROIsButtonPushed(app, event)
            app.expe.bad_ROI_thr = app.CutoffSpinner.Value;
            if strcmp(event.EventName, 'ButtonPushed')
                app.expe.bad_ROI_list = 'unset';
            end
            app.embed_tree(1032);
            s = uistyle('BackgroundColor','r');% 'FontAngle','italic','
            addStyle(app.UITable,s,'cell',[app.expe.bad_ROI_list',4*ones(size(app.expe.bad_ROI_list'))]);            
        end

        % Callback function: MethodDropDown_3, RescaleEventsButton, 
        % SmoothinginptsEditField
        function RescaleEventsButtonPushed(app, event)
            app.expe.rescale_traces(app.MethodDropDown_3.Value, app.SmoothinginptsEditField.Value);
            app.embed_trace(1001);
            app.embed_trace(1012,app.IndividualRescaleFig);
            app.embed_trace(1013,app.GlobalRescaleFig);
            app.embed_trace(1033,app.ScaledIm);
        end

        % Value changed function: DetectUsingDropDown
        function DetectUsingDropDownValueChanged(app, event)
            if strcmp(app.DetectUsingDropDown.Value, 'custom')
                app.CustomRangeEditField.Enable = 'on';
                app.CustomRangeEditField.Value = ['1:',str2double(app.expe.ref.indices.swc_list(end,1))];
            else
                app.CustomRangeEditField.Enable = 'off';
            end
        end

        % Value changed function: MethodDropDown_4
        function MethodDropDown_4ValueChanged(app, event)
            if strcmp(app.MethodDropDown_4.Value, 'corr')
                app.ThresholdEditField.Limits = [0,1];
            elseif strcmp(app.MethodDropDown_4.Value, 'global_amp')
                app.ThresholdEditField.Limits = [0,Inf];
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if strcmp(app.DetectUsingDropDown.Value, 'custom')
                ROIs = str2double(app.CustomRangeEditField.Value);
            elseif strcmp(app.DetectUsingDropDown.Value, 'soma')
                ROIs = 'soma';
            else
                ROIs = [];
            end
            
            app.expe.find_events(ROIs, app.ThresholdEditField.Value, app.MethodDropDown_4.Value);
            
            peak_t = vertcat(app.expe.event.peak_time{:});
            current = 1;
            for record = 1:numel(app.expe.timescale.tp)
                start = current;
                stop = current + app.expe.timescale.tp(record) - 1;
                incl = find(peak_t >= start & peak_t <= stop);
                Top_Node = app.Tree;
                Event_Node = uitreenode(Top_Node,'Text',['Record ',num2str(record)]);
                if ~isempty(incl)                    
                    for ev = incl'
                        uitreenode(Event_Node,'Text',['Event ',num2str(ev), ' t = ', num2str(peak_t(ev))]);  
                        ev_ori = find(cellfun(@(x) any(ismember(x,peak_t(ev))), app.expe.event.peak_time));
                        app.event_index(end+1,:) = [record, ev, ev_ori];
                    end
                else
                    Event_Node.HandleVisibility = 'off';
                end   
                expand(Top_Node)
                current = current + app.expe.timescale.tp(record);
            end
            
            app.embed_trace(1030);
        end

        % Button pushed function: UpdateBadROIsButton
        function UpdateBadROIsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.RawTab;
            app.expe.bad_ROI_thr = app.CutoffSpinner.Value;
            app.expe.bad_ROI_list = 'unset';
            app.embed_tree(1032);
            s = uistyle('BackgroundColor','r');% 'FontAngle','italic','
            addStyle(app.UITable,s,'cell',[app.expe.bad_ROI_list',4*ones(size(app.expe.bad_ROI_list'))]);  
        end

        % Value changed function: SmoothingsSpinner
        function SmoothingsSpinnerValueChanged(app, event)
            app.expe.time_smoothing = ceil([app.SmoothingsSpinner.Value/nanmedian(diff(app.expe.timescale.global_timescale)), 0]); 
            
            %% Display median trace 
            app.expe.plot_original_traces();
            app.embed_trace(1055);
        end

        % Button pushed function: DetectSpinesButton
        function DetectSpinesButtonPushed(app, event)
            
        end

        % Button pushed function: magicButton
        function magicButtonPushed(app, event)
        app.Tree.CheckedNodes = [];
            
            %% Get cluster traces
        cl = app.expe.get_cluster_traces;
        
        %% Pick one index
        clust = app.ClusternumberSpinner.Value;
        
        %% build difference trace
        a = cl(clust,:)' - nanmean(app.expe.binned_data.median_traces,2);
        a = movmean(a,10);
        
        
        %% Find large upmodulated events
        figure();findpeaks(a, 'MinPeakHeight',0.5);
        [~, peak_t_new] = findpeaks(a, 'MinPeakHeight',0.5);
        
        %% List all events
        peak_t = vertcat(app.expe.event.peak_time{:});
        event_index = [];
        current = 1;
        for record = 1:numel(app.expe.timescale.tp)
            start = current;
            stop = current + app.expe.timescale.tp(record) - 1;
            incl = find(peak_t >= start & peak_t <= stop);
            if ~isempty(incl)                    
                for ev = incl'
                    ev_ori = find(cellfun(@(x) any(ismember(x,peak_t(ev))), app.expe.event.peak_time));
                    event_index(end+1,:) = [record, ev, ev_ori];
                end
            end   
            current = current + app.expe.timescale.tp(record);
        end
        
        %% Find corresponding baps
        for t = peak_t_new'
            all         =  vertcat(app.expe.event.peak_time{:});
            [~, loc]    = nanmin(abs(all-t(1))');
            record      = event_index(loc,1);
            event       = event_index(loc,2);
            idx = find(contains({app.Tree.Children(record).Children.Text}, ['Event ',num2str(event),' t = ']));
            app.Tree.CheckedNodes =  [app.Tree.CheckedNodes,;app.Tree.Children(record).Children(idx)];
        end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploreFactorsUIFigure = uifigure('Visible', 'off');
            app.ExploreFactorsUIFigure.Name = 'Explore Factors';
            app.Tree_fig = uiaxes(app.ExploreFactorsUIFigure);
            title(app.Tree_fig, 'Title')
            xlabel(app.Tree_fig, 'X')
            ylabel(app.Tree_fig, 'Y')
            zlabel(app.Tree_fig, 'Z')
            app.Traces_fig = uiaxes(app.ExploreFactorsUIFigure);
            title(app.Traces_fig, 'Title')
            xlabel(app.Traces_fig, 'X')
            ylabel(app.Traces_fig, 'Y')
            zlabel(app.Traces_fig, 'Z')
            app.Tree = uitree(app.ExploreFactorsUIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.GenerateimagesButton = uibutton(app.ExploreFactorsUIFigure, 'push');
            app.GenerateimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateimagesButtonPushed, true);
            app.GenerateimagesButton.Text = 'Generate images';
            app.ShowexcludedROIsCheckBox = uicheckbox(app.ExploreFactorsUIFigure);
            app.ShowexcludedROIsCheckBox.Text = 'Show excluded ROIs';
            app.TabGroup = uitabgroup(app.ExploreFactorsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RawTab = uitab(app.TabGroup);
            app.RawTab.Title = 'Raw';
            app.UITable = uitable(app.RawTab);
            app.UITable.ColumnName = {'ROI'; 'branch'; 'Somatic'; 'bad'};
            app.UITable.RowName = {};
            app.FindBadROIsButton = uibutton(app.RawTab, 'push');
            app.FindBadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectbadROIsButtonPushed, true);
            app.FindBadROIsButton.Text = 'Find Bad ROIs';
            app.CutoffSpinnerLabel = uilabel(app.RawTab);
            app.CutoffSpinnerLabel.Text = 'Cutoff';
            app.CutoffSpinner = uispinner(app.RawTab);
            app.CutoffSpinner.Step = 0.02;
            app.CutoffSpinner.Limits = [0 1];
            app.CutoffSpinner.ValueChangedFcn = createCallbackFcn(app, @DetectbadROIsButtonPushed, true);
            app.ThisstepcanberedoneoncePeakdetecioniscompleteLabel = uilabel(app.RawTab);
            app.ThisstepcanberedoneoncePeakdetecioniscompleteLabel.Text = 'This step can be redone once Peak detecion is complete';
            app.SmoothingsSpinnerLabel = uilabel(app.RawTab);
            app.SmoothingsSpinnerLabel.Text = 'Smoothing (s)';
            app.SmoothingsSpinner = uispinner(app.RawTab);
            app.SmoothingsSpinner.Step = 0.1;
            app.SmoothingsSpinner.Limits = [0 Inf];
            app.SmoothingsSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothingsSpinnerValueChanged, true);
            app.ThisstepcanberedoneoncePeakdetecioniscompleteLabel_2 = uilabel(app.RawTab);
            app.ThisstepcanberedoneoncePeakdetecioniscompleteLabel_2.Text = {'Smoothing applies an asymetrical gaussian filter'; 'that impact all steps of the processing'};
            app.PrepareBinningTab = uitab(app.TabGroup);
            app.PrepareBinningTab.Title = 'Prepare Binning';
            app.BinningModeDropDownLabel = uilabel(app.PrepareBinningTab);
            app.BinningModeDropDownLabel.Text = 'Binning Mode';
            app.BinningModeDropDown = uidropdown(app.PrepareBinningTab);
            app.BinningModeDropDown.Items = {'distance', 'order', 'depth', 'category', 'branch', 'random'};
            app.BinningModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningModeDropDownValueChanged, true);
            app.BinningModeDropDown.Value = 'distance';
            app.BinMetricEditFieldLabel = uilabel(app.PrepareBinningTab);
            app.BinMetricEditFieldLabel.Text = 'Bin Metric';
            app.BinMetricEditField = uieditfield(app.PrepareBinningTab, 'numeric');
            app.BinMetricEditField.ValueChangedFcn = createCallbackFcn(app, @BinningModeDropDownValueChanged, true);
            app.DisplayButtonGroup = uibuttongroup(app.PrepareBinningTab);
            app.DisplayButtonGroup.Title = 'Display';
            app.mediantracerawButton = uiradiobutton(app.DisplayButtonGroup);
            app.mediantracerawButton.Text = 'median trace raw';
            app.mediantracerawButton.Value = true;
            app.mediantracescaledButton = uiradiobutton(app.DisplayButtonGroup);
            app.mediantracescaledButton.Text = 'median trace scaled';
            app.PeakDetectionTab = uitab(app.TabGroup);
            app.PeakDetectionTab.Title = 'Peak Detection';
            app.DetectedEvents = uiaxes(app.PeakDetectionTab);
            title(app.DetectedEvents, 'Title')
            xlabel(app.DetectedEvents, 'X')
            ylabel(app.DetectedEvents, 'Y')
            zlabel(app.DetectedEvents, 'Z')
            app.DetectUsingDropDownLabel = uilabel(app.PeakDetectionTab);
            app.DetectUsingDropDownLabel.Text = 'Detect Using';
            app.DetectUsingDropDown = uidropdown(app.PeakDetectionTab);
            app.DetectUsingDropDown.Items = {'All', 'soma', 'custom'};
            app.DetectUsingDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectUsingDropDownValueChanged, true);
            app.DetectUsingDropDown.Value = 'All';
            app.CustomRangeEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.CustomRangeEditFieldLabel.Enable = 'off';
            app.CustomRangeEditFieldLabel.Text = 'Custom Range';
            app.CustomRangeEditField = uieditfield(app.PeakDetectionTab, 'text');
            app.CustomRangeEditField.Enable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.PeakDetectionTab, 'numeric');
            app.ThresholdEditField.Value = 0.2;
            app.MethodDropDown_4Label = uilabel(app.PeakDetectionTab);
            app.MethodDropDown_4Label.Text = 'Method';
            app.MethodDropDown_4 = uidropdown(app.PeakDetectionTab);
            app.MethodDropDown_4.Items = {'Corr', 'global_amp'};
            app.MethodDropDown_4.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_4ValueChanged, true);
            app.MethodDropDown_4.Value = 'Corr';
            app.DetectButton = uibutton(app.PeakDetectionTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.UpdateBadROIsButton = uibutton(app.PeakDetectionTab, 'push');
            app.UpdateBadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBadROIsButtonPushed, true);
            app.UpdateBadROIsButton.Text = 'Update Bad ROIs';
            app.RescaleTab = uitab(app.TabGroup);
            app.RescaleTab.Title = 'Rescale';
            app.RescaleEventsButton = uibutton(app.RescaleTab, 'push');
            app.RescaleEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleEventsButtonPushed, true);
            app.RescaleEventsButton.Text = 'Rescale Events';
            app.MethodDropDown_3Label = uilabel(app.RescaleTab);
            app.MethodDropDown_3Label.Text = 'Method';
            app.MethodDropDown_3 = uidropdown(app.RescaleTab);
            app.MethodDropDown_3.Items = {'by_trials', 'global'};
            app.MethodDropDown_3.ValueChangedFcn = createCallbackFcn(app, @RescaleEventsButtonPushed, true);
            app.MethodDropDown_3.Value = 'by_trials';
            app.SmoothinginptsEditFieldLabel = uilabel(app.RescaleTab);
            app.SmoothinginptsEditFieldLabel.Text = 'Smoothing (in pts)';
            app.SmoothinginptsEditField = uieditfield(app.RescaleTab, 'numeric');
            app.SmoothinginptsEditField.ValueChangedFcn = createCallbackFcn(app, @RescaleEventsButtonPushed, true);
            app.SuggestedvalueLabel = uilabel(app.RescaleTab);
            app.SuggestedvalueLabel.Text = 'Suggested value :';
            app.TabGroup2 = uitabgroup(app.RescaleTab);
            app.IndividuaScalingTab = uitab(app.TabGroup2);
            app.IndividuaScalingTab.Title = 'Individua Scaling';
            app.IndividualRescaleFig = uiaxes(app.IndividuaScalingTab);
            title(app.IndividualRescaleFig, 'Title')
            xlabel(app.IndividualRescaleFig, 'X')
            ylabel(app.IndividualRescaleFig, 'Y')
            zlabel(app.IndividualRescaleFig, 'Z')
            app.GlobalScalingTab = uitab(app.TabGroup2);
            app.GlobalScalingTab.Title = 'Global Scaling';
            app.GlobalRescaleFig = uiaxes(app.GlobalScalingTab);
            title(app.GlobalRescaleFig, 'Title')
            xlabel(app.GlobalRescaleFig, 'X')
            ylabel(app.GlobalRescaleFig, 'Y')
            zlabel(app.GlobalRescaleFig, 'Z')
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.ScaledIm = uiaxes(app.ImageTab);
            title(app.ScaledIm, 'Title')
            xlabel(app.ScaledIm, 'X')
            ylabel(app.ScaledIm, 'Y')
            zlabel(app.ScaledIm, 'Z')
            app.DimReductionTab = uitab(app.TabGroup);
            app.DimReductionTab.Title = 'Dim Reduction';
            app.Loadings_fig = uiaxes(app.DimReductionTab);
            title(app.Loadings_fig, 'Title')
            xlabel(app.Loadings_fig, 'X')
            ylabel(app.Loadings_fig, 'Y')
            zlabel(app.Loadings_fig, 'Z')
            app.TreeDisplayButtonGroup = uibuttongroup(app.DimReductionTab);
            app.TreeDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TreeDisplayButtonGroupSelectionChanged, true);
            app.TreeDisplayButtonGroup.Title = 'Tree Display';
            app.BranchesButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.BranchesButton.Text = 'Branches';
            app.BranchesButton.Value = true;
            app.FactorsButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.FactorsButton.Text = 'Factors';
            app.ClustersButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.ClustersButton.Text = 'Clusters';
            app.MethodDropDownLabel = uilabel(app.TreeDisplayButtonGroup);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.TreeDisplayButtonGroup);
            app.MethodDropDown.Items = {'pca', 'factoran', 'nnmf'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'pca';
            app.MethodDropDown_2Label = uilabel(app.TreeDisplayButtonGroup);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.TreeDisplayButtonGroup);
            app.MethodDropDown_2.Items = {'none', 'kmeans', 'hierarchical', 'dbscan'};
            app.MethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MethodDropDown_2.Value = 'none';
            app.NFactorsSpinnerLabel = uilabel(app.TreeDisplayButtonGroup);
            app.NFactorsSpinnerLabel.Text = 'N Factors';
            app.NFactorsSpinner = uispinner(app.TreeDisplayButtonGroup);
            app.NFactorsSpinner.Limits = [0 20];
            app.NFactorsSpinner.RoundFractionalValues = 'on';
            app.NFactorsSpinner.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.NFactorsSpinner.Value = 6;
            app.NClustEditFieldLabel = uilabel(app.TreeDisplayButtonGroup);
            app.NClustEditFieldLabel.Text = 'N Clust';
            app.NClustEditField = uieditfield(app.TreeDisplayButtonGroup, 'numeric');
            app.NClustEditField.Limits = [1 Inf];
            app.NClustEditField.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.NClustEditField.Value = 1;
            app.RefreshButton = uibutton(app.DimReductionTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.MetricDropDownLabel = uilabel(app.DimReductionTab);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.DimReductionTab);
            app.MetricDropDown.Items = {'full', 'peaks', 'whisker', 'encoder', 'wheel'};
            app.MetricDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricDropDownValueChanged, true);
            app.MetricDropDown.Value = 'peaks';
            app.SpinedetectionTab = uitab(app.TabGroup);
            app.SpinedetectionTab.Title = 'Spine detection';
            app.DetectSpinesButton = uibutton(app.SpinedetectionTab, 'push');
            app.DetectSpinesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSpinesButtonPushed, true);
            app.DetectSpinesButton.Text = 'Detect Spines';
            app.magicButton = uibutton(app.SpinedetectionTab, 'push');
            app.magicButton.ButtonPushedFcn = createCallbackFcn(app, @magicButtonPushed, true);
            app.magicButton.Text = 'magic';
            app.ThrpeaksEditFieldLabel = uilabel(app.SpinedetectionTab);
            app.ThrpeaksEditFieldLabel.Text = 'Thr peaks';
            app.ThrpeaksEditField = uieditfield(app.SpinedetectionTab, 'numeric');
            app.ClusternumberSpinnerLabel = uilabel(app.SpinedetectionTab);
            app.ClusternumberSpinnerLabel.Text = 'Cluster number';
            app.ClusternumberSpinner = uispinner(app.SpinedetectionTab);
            app.ClusternumberSpinner.Limits = [1 20];
            app.ClusternumberSpinner.Value = 1;
            app.ReloadOriginalDataPanel = uipanel(app.ExploreFactorsUIFigure);
            app.ReloadOriginalDataPanel.Title = 'Reload Original Data';
            app.SelectEventButton = uibutton(app.ReloadOriginalDataPanel, 'push');
            app.SelectEventButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEventButtonPushed, true);
            app.SelectEventButton.Text = 'Select Event';
            app.LoaddataatTimepointButton = uibutton(app.ReloadOriginalDataPanel, 'push');
            app.LoaddataatTimepointButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataatTimepointButtonPushed, true);
            app.LoaddataatTimepointButton.Text = {'Load data '; 'at Timepoint'};
            app.LoadTrialButton = uibutton(app.ReloadOriginalDataPanel, 'push');
            app.LoadTrialButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialButtonPushed, true);
            app.LoadTrialButton.Text = 'Load Trial';
            app.RenderingLabel = uilabel(app.ReloadOriginalDataPanel);
            app.RenderingLabel.Text = 'Rendering';
            app.RenderingMode = uidropdown(app.ReloadOriginalDataPanel);
            app.RenderingMode.Items = {'mosaic', 'realistic'};
            app.RenderingMode.Value = 'mosaic';
            app.BranchDropDownLabel = uilabel(app.ExploreFactorsUIFigure);
            app.BranchDropDownLabel.Text = 'Branch';
            app.BranchDropDown = uidropdown(app.ExploreFactorsUIFigure);
            app.BranchDropDown.Items = {'All'};
            app.BranchDropDown.ValueChangedFcn = createCallbackFcn(app, @BranchDropDownValueChanged, true);
            app.BranchDropDown.Value = 'All';
            % Show the figure after all components are created
            app.ExploreFactorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = explore_factors(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploreFactorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploreFactorsUIFigure)
        end
    end
end